classdef APP_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GrficarButton                matlab.ui.control.Button
        CargarsealButton             matlab.ui.control.Button
        TiempodemuestreosEditField   matlab.ui.control.NumericEditField
        TiempodemuestreosEditFieldLabel  matlab.ui.control.Label
        FrecuenciademuestreoHzEditField  matlab.ui.control.NumericEditField
        FrecuenciademuestreoHzEditFieldLabel  matlab.ui.control.Label
        NmerodemuestrasEditField     matlab.ui.control.NumericEditField
        TiempodemuestreosLabel       matlab.ui.control.Label
        FuentedelasealDropDown       matlab.ui.control.DropDown
        FuentedelasealDropDownLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        x; % Arreglo con los datos de una señal
        Fs; %Frecuencia de muestro de la señal en Hz
        T_muestreo; % Tiempo de muestreo de la señal
        N; %Numero de muestras de la señal
    end
    
    methods (Access = private)
        
        function [N_muestras] = ActualizarNumeroDeMuestras(app)
            
            N_muestras = app.Fs * app.T_muestreo; %Calcular el valor para Numero de muestras
            app.NmerodemuestrasEditField.Value = N_muestras; %Actualizar el numero de muestras del cajon
            app.N = N_muestras %Actualizar el numero de muestras global
            app.NmerodemuestrasEditField.ValueDisplayFormat = '%.0f'; %Evitar formato notación cientifica
       
        end
        
        function  Set_propiedades(app, valor_Fs, valor_T)
            %funcion que setea las propiedades de la señal, en funcion de
            %su origen

            %validar que no se carguen datos vácios
            if ( isempty(valor_Fs))
                valor_Fs = 100; 
            end

            if( isempty(valor_T))
                valor_T=1;
            end

            %Colocar el valor de la Fs desde el cajon o desde un .mat
            app.Fs = valor_Fs; %actualizar valor global de Fs
            app.FrecuenciademuestreoHzEditField.Value = valor_Fs;%Actualizar en cajon

            %Colocar el valor de tiempo de muestro desde el cajon o desde un .mat
            app.T_muestreo = valor_T; %actualizar valor global de N
            app.TiempodemuestreosEditField.Value = valor_T; %Actualizar en cajon

            %Actualizar valor de numero de muestras
            ActualizarNumeroDeMuestras(app);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarsealButton
        function CargarsealButtonPushed(app, event)
            
            [file,location] = uigetfile('*.mat');

            if isequal(file,0)
                disp('User selected Cancel'); %Sino se seleciona archivo, regresar
                return
            else
            disp(['User selected ', fullfile(location,file)]);
            end     

            data = load(fullfile(location, file)); %cargar datos
            app.x = data.x; %cargar arreglo con la señal
           

            %Setear propiedades de la señal desde .mat
            Set_propiedades(app, data.Fs, data.T_muestreo); 


        end

        % Value changed function: FuentedelasealDropDown
        function FuentedelasealDropDownValueChanged(app, event)
            value = app.FuentedelasealDropDown.Value;
            
            %Deshabilitar funciones no necesarias para cada opcion
            if(strcmp(value, 'Cargar desde .mat'))
                app.CargarsealButton.Enable = "on"; %habilitar el boton

            elseif (strcmp(value, 'Obtener datos desde módulo físico'))
                app.CargarsealButton.Enable = "off"; %Si se usara el modulo físico, deshabilitar opcion para cargar data

              
            end


        end

        % Value changed function: FrecuenciademuestreoHzEditField
        function FrecuenciademuestreoHzEditFieldValueChanged(app, event)
            value = app.FrecuenciademuestreoHzEditField.Value;

            %Setear propiedades de la señal desde cajon
            Set_propiedades(app, value, app.T_muestreo);  
            app.FrecuenciademuestreoHzEditField.ValueDisplayFormat = '%.0f'; %Mantener formato entero


        end

        % Value changed function: TiempodemuestreosEditField
        function TiempodemuestreosEditFieldValueChanged(app, event)
            value = app.TiempodemuestreosEditField.Value;
            
            %Setear propiedades de la señal desde cajon
            Set_propiedades(app, app.Fs,value);
            app.FrecuenciademuestreoHzEditField.ValueDisplayFormat = '%.0f'; %Mantener el formato entero

            
        end

        % Button pushed function: GrficarButton
        function GrficarButtonPushed(app, event)
            %Gráficar
            t_vect = (0:app.N -1)*(1/app.Fs);
            plot(app.UIAxes,t_vect, app.x);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'x[n]')
            xlabel(app.UIAxes, 't(s)')
            ylabel(app.UIAxes, 'x[n]')
            zlabel(app.UIAxes, 'Z')
            app.FuentedelasealDropDownLabel = uilabel(app.UIFigure);
            app.FuentedelasealDropDownLabel.Text = 'Fuente de la señal';
            app.FuentedelasealDropDown = uidropdown(app.UIFigure);
            app.FuentedelasealDropDown.Items = {'Cargar desde .mat', 'Obtener datos desde módulo físico'};
            app.FuentedelasealDropDown.ValueChangedFcn = createCallbackFcn(app, @FuentedelasealDropDownValueChanged, true);
            app.FuentedelasealDropDown.Value = 'Cargar desde .mat';
            app.TiempodemuestreosLabel = uilabel(app.UIFigure);
            app.TiempodemuestreosLabel.Text = 'Número de muestras';
            app.NmerodemuestrasEditField = uieditfield(app.UIFigure, 'numeric');
            app.NmerodemuestrasEditField.Limits = [0 Inf];
            app.NmerodemuestrasEditField.AllowEmpty = 'on';
            app.NmerodemuestrasEditField.Editable = 'off';
            app.NmerodemuestrasEditField.Value = 100;
            app.FrecuenciademuestreoHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciademuestreoHzEditFieldLabel.Text = 'Frecuencia de muestreo (Hz)';
            app.FrecuenciademuestreoHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrecuenciademuestreoHzEditField.Limits = [100 10000];
            app.FrecuenciademuestreoHzEditField.AllowEmpty = 'on';
            app.FrecuenciademuestreoHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciademuestreoHzEditFieldValueChanged, true);
            app.FrecuenciademuestreoHzEditField.Value = 100;
            app.TiempodemuestreosEditFieldLabel = uilabel(app.UIFigure);
            app.TiempodemuestreosEditFieldLabel.Text = 'Tiempo de muestreo (s)';
            app.TiempodemuestreosEditField = uieditfield(app.UIFigure, 'numeric');
            app.TiempodemuestreosEditField.Limits = [0.5 15];
            app.TiempodemuestreosEditField.ValueChangedFcn = createCallbackFcn(app, @TiempodemuestreosEditFieldValueChanged, true);
            app.TiempodemuestreosEditField.Value = 1;
            app.CargarsealButton = uibutton(app.UIFigure, 'push');
            app.CargarsealButton.ButtonPushedFcn = createCallbackFcn(app, @CargarsealButtonPushed, true);
            app.CargarsealButton.Text = 'Cargar señal';
            app.GrficarButton = uibutton(app.UIFigure, 'push');
            app.GrficarButton.ButtonPushedFcn = createCallbackFcn(app, @GrficarButtonPushed, true);
            app.GrficarButton.Text = 'Gráficar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end